classdef Control_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OpenSerialPortButton            matlab.ui.control.Button
        CloseSerialPortButton           matlab.ui.control.Button
        readtrajectoryButton            matlab.ui.control.Button
        downloadtrajectorytorobotButton  matlab.ui.control.Button
        OpenLEFTgrapperButton           matlab.ui.control.Button
        CloseLEFTgrapperButton          matlab.ui.control.Button
        OpenRIGHTgrapperButton          matlab.ui.control.Button
        CloseRIGHTgrapperButton         matlab.ui.control.Button
        SwingRIGHTInitPoseButton        matlab.ui.control.Button
        SwingRIGHTExecutetrajectoryButton  matlab.ui.control.Button
        AbortAllActionsButton           matlab.ui.control.Button
        SendNotifyMsgButton             matlab.ui.control.Button
        trajectoryfilesListBoxLabel     matlab.ui.control.Label
        trajectoryfilesListBox          matlab.ui.control.ListBox
        SwingLEFTInitPoseButton         matlab.ui.control.Button
        SwingLEFTExecutetrajectoryButton  matlab.ui.control.Button
        SwingdirectionListBoxLabel      matlab.ui.control.Label
        SwingdirectionListBox           matlab.ui.control.ListBox
        AxesLeftPos                     matlab.ui.control.UIAxes
        AxesRightPos                    matlab.ui.control.UIAxes
        OpenBOTHButton                  matlab.ui.control.Button
        CloseBOTHButton                 matlab.ui.control.Button
        AxesLeftVel                     matlab.ui.control.UIAxes
        AxesRightVel                    matlab.ui.control.UIAxes
        PlottrajectoryButton            matlab.ui.control.Button
        GetfeedbacktrajectoryButton     matlab.ui.control.Button
        clearplotButton                 matlab.ui.control.Button
        NoteforscreenshootEditFieldLabel  matlab.ui.control.Label
        NoteforscreenshootEditField     matlab.ui.control.EditField
        delaytimemsEditFieldLabel       matlab.ui.control.Label
        delaytimemsEditField            matlab.ui.control.NumericEditField
        SetdelayButton                  matlab.ui.control.Button
        LeftKpEditFieldLabel            matlab.ui.control.Label
        LeftKpEditField                 matlab.ui.control.NumericEditField
        RightKpEditFieldLabel           matlab.ui.control.Label
        RightKpEditField                matlab.ui.control.NumericEditField
        SetPosKpButton                  matlab.ui.control.Button
        debugoptionListBoxLabel         matlab.ui.control.Label
        debugoptionListBox              matlab.ui.control.ListBox
        SetdebugprintButton             matlab.ui.control.Button
        SetPosKiButton                  matlab.ui.control.Button
        LeftKiEditFieldLabel            matlab.ui.control.Label
        LeftKiEditField                 matlab.ui.control.NumericEditField
        RightKiEditFieldLabel           matlab.ui.control.Label
        RightKiEditField                matlab.ui.control.NumericEditField
        SetPosKdButton                  matlab.ui.control.Button
        LeftKdEditFieldLabel            matlab.ui.control.Label
        LeftKdEditField                 matlab.ui.control.NumericEditField
        RightKdEditFieldLabel           matlab.ui.control.Label
        RightKdEditField                matlab.ui.control.NumericEditField
        SetVelKpButton                  matlab.ui.control.Button
        SetVelKiButton                  matlab.ui.control.Button
        SetVelKdButton                  matlab.ui.control.Button
        LeftKpEditField_4Label          matlab.ui.control.Label
        LeftKpEditField_4               matlab.ui.control.NumericEditField
        RightKpEditField_4Label         matlab.ui.control.Label
        RightKpEditField_4              matlab.ui.control.NumericEditField
        LeftKiEditField_2Label          matlab.ui.control.Label
        LeftKiEditField_2               matlab.ui.control.NumericEditField
        RightKiEditField_2Label         matlab.ui.control.Label
        RightKiEditField_2              matlab.ui.control.NumericEditField
        LeftKdEditField_2Label          matlab.ui.control.Label
        LeftKdEditField_2               matlab.ui.control.NumericEditField
        RightKdEditField_2Label         matlab.ui.control.Label
        RightKdEditField_2              matlab.ui.control.NumericEditField
        SetcloseButton                  matlab.ui.control.Button
        closetimepercentEditFieldLabel  matlab.ui.control.Label
        closetimepercentEditField       matlab.ui.control.NumericEditField
        SetInitKpButton                 matlab.ui.control.Button
        SetInitKiButton                 matlab.ui.control.Button
        SetInitKdButton                 matlab.ui.control.Button
        InitKpEditFieldLabel            matlab.ui.control.Label
        InitKpEditField                 matlab.ui.control.NumericEditField
        InitKiEditFieldLabel            matlab.ui.control.Label
        InitKiEditField                 matlab.ui.control.NumericEditField
        InitKdEditFieldLabel            matlab.ui.control.Label
        InitKdEditField                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        traj_idx = 1; % Description
        s;
        send_traj = struct;
        send_on = 0;
        
        % after push swingXXXexecutetrajectory, start a thread to listen to ack from 
        listen_traj_ack = 0;
        listen_ack_counter = 0;
        % data for UI axes
        data_left_pos_t = [];
        data_left_pos_val = [];
        data_right_pos_t = [];
        data_right_pos_val = [];
        data_left_vel_t = [];
        data_left_vel_val = [];
        data_right_vel_t = [];
        data_right_vel_val = [];
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serial('COM9');
            set(app.s,'BaudRate',115200);
            app.s.ByteOrder = 'littleEndian';
            
            % create trajectoryfilesDropdown list
            matFiles = dir([pwd '\*.mat']);
            
            list_of_files = {};
            for Index = 1:length(matFiles)
                baseFileName = matFiles(Index).name;
                list_of_files{end+1} = baseFileName;
            end
            set(app.trajectoryfilesListBox, 'items', list_of_files);
            set(app.trajectoryfilesListBox, 'value',matFiles(1).name);
            
        end

        % Button pushed function: OpenSerialPortButton
        function OpenSerialPortButtonPushed(app, event)
            fopen(app.s);
            app.OpenLEFTgrapperButton.Enable = 'on';
            app.OpenRIGHTgrapperButton.Enable = 'on';
            app.CloseLEFTgrapperButton.Enable = 'on';
            app.CloseRIGHTgrapperButton.Enable = 'on';
            app.OpenBOTHButton.Enable = 'on';
            app.CloseBOTHButton.Enable = 'on';
            app.AbortAllActionsButton.Enable = 'on';
            app.downloadtrajectorytorobotButton.Enable = 'on';
            app.SendNotifyMsgButton.Enable = 'on';
            app.SwingRIGHTExecutetrajectoryButton.Enable = 'on';
            app.SwingRIGHTInitPoseButton.Enable = 'on';
            app.GetfeedbacktrajectoryButton.Enable = 'on';
        end

        % Button pushed function: CloseSerialPortButton
        function CloseSerialPortButtonPushed(app, event)
            fclose(app.s);
            app.OpenLEFTgrapperButton.Enable = 'off';
            app.OpenRIGHTgrapperButton.Enable = 'off';
            app.CloseLEFTgrapperButton.Enable = 'off';
            app.CloseRIGHTgrapperButton.Enable = 'off';
            app.SwingRIGHTInitPoseButton.Enable = 'off';
            app.downloadtrajectorytorobotButton.Enable = 'off';
            app.SwingRIGHTExecutetrajectoryButton.Enable = 'off';
            app.AbortAllActionsButton.Enable = 'off';
            app.SendNotifyMsgButton.Enable = 'off';
            app.OpenBOTHButton.Enable = 'off';
            app.CloseBOTHButton.Enable = 'off';
            app.GetfeedbacktrajectoryButton.Enable = 'off';
        end

        % Button pushed function: OpenRIGHTgrapperButton
        function OpenRIGHTgrapperButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(9);    
            SendData.value = int16(0); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            % no ack
        end

        % Button pushed function: CloseRIGHTgrapperButton
        function CloseRIGHTgrapperButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(9);    
            SendData.value = int16(1); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            % no ack
        end

        % Button pushed function: OpenLEFTgrapperButton
        function OpenLEFTgrapperButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(8);    
            SendData.value = int16(0); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            % no ack
        end

        % Button pushed function: CloseLEFTgrapperButton
        function CloseLEFTgrapperButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(8);    
            SendData.value = int16(1); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            % no ack
        end

        % Button pushed function: downloadtrajectorytorobotButton
        function downloadtrajectorytorobotButtonPushed(app, event)
            app.send_on = 1;                
            send_left_or_right = 1;     % if 1, send left trajectory, if 2, send right trajectory
            app.traj_idx = 1;
            FSM_state = 1;  % this sender is a finite state machine. 
                            % state = 1: wait for the ack of the notification package 
                            % state = 2: send a trajectory package
                            % state = 3: wait for the ack of the previous trajectory package
                            % state = 4: a notification message to start trajectory
            ack_wait_times = 0;
            databuffer_wait = 0;
            MAX_ACK_WAIT_TIMES = 10;   % if ack_wait_times
            ReceiveData = struct;                
            while (app.send_on)
            
                switch FSM_state
                    case 1
                        while (app.s.BytesAvailable == 0)
                            disp('no data in serial buffer')
                            databuffer_wait  = databuffer_wait + 1;
                            if (databuffer_wait > 100)
                                app.send_on = 0;
                                break;
                            end
                        end
                        if (app.send_on == 0)
                            break;
                        end
                        % wait for the ack of the notification package 
                        A = fread(app.s,1,'uchar');
                        if (A(1) == uint8(170)) % the head of a frame 
                            B = fread(app.s,13,'uchar');
                            crc = B(12:13);
                            crc16 = crcccitt([A(1);B(1:11)]);
                            my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian
            
                            if (sum(my_crc == crc) == 2)
                                ReceiveData.type = B(1);  
                                if (ReceiveData.type == 40)  %  ACK 
                                    
                                    if (send_left_or_right == 1)
                                        disp('got ACK from MCU, ready to upload left trajectory point 1')
                                    else
                                        
                                        disp('got ACK from MCU, ready to upload right trajectory point 1')
                                    end
                                    FSM_state = 2;
                                    continue;
                                end
                            end
                        end
                        
                        ack_wait_times = ack_wait_times + 1;
                        if (ack_wait_times > MAX_ACK_WAIT_TIMES)
                            disp('no ACK!!!! something is wrong')
                        	break;
                        end
                        
                    case 2
                        app.traj_idx
                        SendData.flag = uint8(170);
                        if (send_left_or_right == 1)
                            SendData.type = uint8(3);    % type = 3 means this message contains a left trajectory point
                            SendData.value = int16(app.traj_idx-1);
                            % I have to invert this. 
                            SendData.position = int32(app.send_traj.tgt_state(2,app.traj_idx)*1000);
                            SendData.velocity = int32(app.send_traj.tgt_state(5,app.traj_idx)*1000);
                        else
                            SendData.type = uint8(4);    % type = 4 means this message contains a right trajectory point
                            SendData.value = int16(app.traj_idx-1);
                            SendData.position = int32(app.send_traj.tgt_state(3,app.traj_idx)*1000);
                            SendData.velocity = int32(app.send_traj.tgt_state(6,app.traj_idx)*1000);
                        end
                        SendData.crc = pack_and_cal_crc(SendData);
                        send_struct(app.s, SendData);
                        
                        FSM_state = 3;
                        ack_wait_times = 0;
                    case 3
                        while (app.s.BytesAvailable == 0)
                            disp('no data in serial buffer')
                        end
                        app.s.BytesAvailable
                        % wait for the ack of the notification package 
                        A = fread(app.s,1,'uchar');
                        if (A(1) == uint8(170)) % the head of a frame 
                            B = fread(app.s,13,'uchar');
                            crc = B(12:13);
                            crc16 = crcccitt([A(1);B(1:11)]);
                            my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian
            
                            if (sum(my_crc == crc) == 2)
                                ReceiveData.type = B(1);   
                                if (ReceiveData.type == 40)   %  ACK 
                                    FSM_state = 2;
                                    if app.traj_idx < size(app.send_traj.tgt_state,2)
                                        app.traj_idx = app.traj_idx + 1;
                                        if (send_left_or_right == 1)
                                            X = sprintf('got ACK from MCU, ready to upload left trajectory point %d', app.traj_idx);
                                            disp(X);
                                        else
                                            X = sprintf('got ACK from MCU, ready to upload right trajectory point %d', app.traj_idx);
                                            disp(X);
                                        end
                                        continue;
                                    else % traj_idx = step+1
                                        if (send_left_or_right == 1)
                                            send_left_or_right = 2;
                                            FSM_state = 2;
                                            ack_wait_times = 0;
                                            app.traj_idx = 1;
                                            continue;
                                        elseif send_left_or_right == 2
                                            FSM_state = 4;
                                            ack_wait_times = 0;
                                            disp('got ACK from MCU, notify finish sending trajectory')
                                            continue;
                                        end
                                    end
                                elseif (ReceiveData.type == 100)
                                    X = sprintf('got ERROR from MCU, resend trajectory point %d', traj_idx);
                                    disp(X);
                                    FSM_state = 2;
                                end
                            end
                        end
                        ack_wait_times = ack_wait_times + 1;
                        if (ack_wait_times > MAX_ACK_WAIT_TIMES)
                            disp('no ACK!!!! resend current data point')
                        	FSM_state = 2;
                        end
                        
                    case 4
                        SendData.flag = uint8(170);
                        SendData.type = uint8(5);    
                        SendData.value = int16(app.traj_idx); 
                        SendData.position = int32(0);
                        SendData.velocity = int32(0);
                        SendData.crc = pack_and_cal_crc(SendData);
                        send_struct(app.s, SendData);
                        
                        disp('notify MCU finish sending trajectory')
                        pause(0.2);
                        % take ACK
                        A = fread(app.s,1,'uchar');
                        if (A(1) == uint8(170)) % the head of a frame 
                            B = fread(app.s,13,'uchar');
                            crc = B(12:13);
                            crc16 = crcccitt([A(1);B(1:11)]);
                            my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian
            
                            if (sum(my_crc == crc) == 2)
                                ReceiveData.type = B(1);  
                                if (ReceiveData.type == 40)  %  ACK 
                                    disp('get MUC sending trajectory ack')
                                end
                            end
                        end
                        
                        break;
                        
                end
                
                
                pause(0.05);
            end            
            app.send_on = 0;
        end

        % Button pushed function: SwingRIGHTInitPoseButton
        function SwingRIGHTInitPoseButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(7);    
            if (strcmp(app.SwingdirectionListBox.Value, 'Forward'))
               SendData.value = int16(0); 
            else
               SendData.value = int16(1); 
            end
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            disp('ask MCU to move to init pose')
            pause(0.2);
            % take ACK
            A = fread(app.s,1,'uchar');
            if (A(1) == uint8(170)) % the head of a frame 
                B = fread(app.s,13,'uchar');
                crc = B(12:13);
                crc16 = crcccitt([A(1);B(1:11)]);
                my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian

                if (sum(my_crc == crc) == 2)
                    ReceiveData.type = B(1);  
                    if (ReceiveData.type == 40)  %  ACK 
                        disp('get MUC move to init pose ack')
                    end
                end
            end
        end

        % Button pushed function: SwingRIGHTExecutetrajectoryButton
        function SwingRIGHTExecutetrajectoryButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(6);    
            if (strcmp(app.SwingdirectionListBox.Value, 'Forward'))
               SendData.value = int16(0); 
            else
               SendData.value = int16(1); 
            end
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            
            disp('ask MCU to start to execute trajectory')
            pause(0.2);
            % take ACK
            A = fread(app.s,1,'uchar');
            if (A(1) == uint8(170)) % the head of a frame 
                B = fread(app.s,13,'uchar');
                crc = B(12:13);
                crc16 = crcccitt([A(1);B(1:11)]);
                my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian

                if (sum(my_crc == crc) == 2)
                    ReceiveData.type = B(1);  
                    if (ReceiveData.type == 40)  %  ACK 
                        disp('get MUC execute trajectory ack')
                    end
                end
            end


        end

        % Button pushed function: readtrajectoryButton
        function readtrajectoryButtonPushed(app, event)
            x = strcat('load file', app.trajectoryfilesListBox.Value);
            disp(x);
            app.send_traj = load(app.trajectoryfilesListBox.Value);
            app.PlottrajectoryButton.Enable = 'on';
        end

        % Button pushed function: AbortAllActionsButton
        function AbortAllActionsButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(0);    
            SendData.value = int16(0); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.s);
            delete(app)
            
        end

        % Button pushed function: SendNotifyMsgButton
        function SendNotifyMsgButtonPushed(app, event)
            SendData = struct;
            % first config SendData to be a notification message
            SendData.flag = uint8(170);
            SendData.type = uint8(2);    % type = 2 means this message notify monkey to prepare to receive trajectory
            SendData.value = int16(0);
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            
            send_struct(app.s, SendData);
        end

        % Button pushed function: SwingLEFTInitPoseButton
        function SwingLEFTInitPoseButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(9);    
            SendData.value = int16(0); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
        end

        % Button pushed function: OpenBOTHButton
        function OpenBOTHButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(8);    
            SendData.value = int16(0); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            pause(0.3);
            SendData.flag = uint8(170);
            SendData.type = uint8(9);    
            SendData.value = int16(0); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
        end

        % Button pushed function: CloseBOTHButton
        function CloseBOTHButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(9);    
            SendData.value = int16(1); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            pause(0.3);
            SendData.flag = uint8(170);
            SendData.type = uint8(8);    
            SendData.value = int16(1); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
        end

        % Button pushed function: PlottrajectoryButton
        function PlottrajectoryButtonPushed(app, event)
            hold( app.AxesLeftPos, 'on' );
            hold( app.AxesRightPos, 'on' );
            hold( app.AxesLeftVel, 'on' );
            hold( app.AxesRightVel, 'on' );
            plot(app.AxesLeftPos, app.send_traj.tgt_state(2,:)); 
            plot(app.AxesRightPos, app.send_traj.tgt_state(3,:)); 
            plot(app.AxesLeftVel, app.send_traj.tgt_state(5,:)); 
            plot(app.AxesRightVel, app.send_traj.tgt_state(6,:)); 
        end

        % Button pushed function: GetfeedbacktrajectoryButton
        function GetfeedbacktrajectoryButtonPushed(app, event)
            
%             flushinput(app.s); % clear serial buffer
            
            % request trajectory feedback
            SendData.flag = uint8(170);
            SendData.type = uint8(49);    
            SendData.value = int16(0); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            
            
            % ready to listen to ack
            app.listen_traj_ack = 1;  
            databuffer_wait = 0;
            ReceiveData = struct;  
            app.listen_ack_counter = 0;
            traj_ack_FSM_state = 1;  % this ack listener is a finite state machine. 
                            
            while (app.listen_traj_ack)
                pause(0.01);
                switch traj_ack_FSM_state
                    case 1
                        while (app.s.BytesAvailable == 0)
                            %just quietly wait
%                              disp('no data in buffer')
                            databuffer_wait  = databuffer_wait + 1;
                            if (databuffer_wait > 600)
                                app.listen_traj_ack = 0;
                                break;
                            end
                        end
                        databuffer_wait = 0;
                        if (app.listen_traj_ack == 0)
                            break;
                        end
                        % wait for the traj ack 
                        A = fread(app.s,1,'uchar');
                        if (A(1) == uint8(170)) % the head of a frame 
                            B = fread(app.s,13,'uchar');
                            crc = B(12:13);
                            crc16 = crcccitt([A(1);B(1:11)]);
                            my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian
            
                            if (sum(my_crc == crc) == 2)
                                ReceiveData.type = B(1);  
                                if (ReceiveData.type == 50)  %  traj pack 
                                    app.listen_ack_counter = app.listen_ack_counter + 1;
                                    ReceiveData.value = typecast(uint8(B(2:3)), 'int16');   
                                    
                                    ReceiveData.pos = typecast(uint8(B(4:7)), 'int32'); 
                                    ReceiveData.pos = double(ReceiveData.pos)/1000;
                                    ReceiveData.vel = typecast(uint8(B(8:11)), 'int32'); 
                                    ReceiveData.vel = double(ReceiveData.vel)/1000;  
                                    x = strcat('receive ', num2str(app.listen_ack_counter), ' traj package with pos ', num2str(ReceiveData.pos), ' and vel ', num2str(ReceiveData.vel));
                                    disp(x)
                                    if (ReceiveData.value == 0) % left hand 
                                        app.data_left_pos_val = [app.data_left_pos_val ReceiveData.pos];
                                        app.data_left_vel_val = [app.data_left_vel_val ReceiveData.vel];
                                    else
                                        app.data_right_pos_val = [app.data_right_pos_val ReceiveData.pos];
                                        app.data_right_vel_val = [app.data_right_vel_val ReceiveData.vel]; 
                                    end

                                    % send ack
                                    SendData.flag = uint8(170);
                                    SendData.type = uint8(51);    
                                    SendData.value = int16(0); 
                                    SendData.position = int32(0);
                                    SendData.velocity = int32(0);
                                    SendData.crc = pack_and_cal_crc(SendData);
                                    send_struct(app.s, SendData);
                                    
                                elseif (ReceiveData.type == 52) % end of traj pack
                                    traj_ack_FSM_state = 2;
                                else
                                    disp('expect traj pack, got wrong type')
                                end
                            else
                                disp('wrong crc')
                            end
                        else
                            disp('wrong frame head')
                        end
                      case 2
                          disp('receive data ends')
                          app.listen_traj_ack = 0;
                          break;                     
                end
            end
            plot(app.AxesLeftPos, app.data_left_pos_val);
            plot(app.AxesRightPos, app.data_right_pos_val);
            plot(app.AxesLeftVel, app.data_left_vel_val);
            plot(app.AxesRightVel, app.data_right_vel_val);
            
            name = convertCharsToStrings(datestr(datetime('now')))+'.mat';
            [fnm,pth] = uiputfile(name);
            if ischar(fnm)
                AxesLeftPos = app.AxesLeftPos;
                data_left_pos_val = app.data_left_pos_val;
                AxesRightPos = app.AxesRightPos;
                data_right_pos_val = app.data_right_pos_val;
                AxesLeftVel = app.AxesLeftVel;
                data_left_vel_val = app.data_left_vel_val;
                AxesRightVel = app.AxesRightVel;
                data_right_vel_val = app.data_right_vel_val;
                save(fullfile(pth,fnm),'AxesLeftPos','data_left_pos_val','AxesRightPos','data_right_pos_val','AxesLeftVel','data_left_vel_val','AxesRightVel','data_right_vel_val');
            end
            
            app.data_left_pos_t = [];
            app.data_left_pos_val = [];
            app.data_right_pos_t = [];
            app.data_right_pos_val = [];
            app.data_left_vel_t = [];
            app.data_left_vel_val = [];
            app.data_right_vel_t = [];
            app.data_right_vel_val = [];
            app.listen_ack_counter = 0;
            
            % testing code 
%             while (app.listen_ack_counter < 300)
%                 app.listen_ack_counter = app.listen_ack_counter + 1;
%                 
%                 app.data_left_pos_t = [app.data_left_pos_t app.listen_ack_counter];
%                 app.data_left_pos_val = [app.data_left_pos_val app.send_traj.tgt_state(2,app.listen_ack_counter)];
%                 
%                 
%                 app.data_right_pos_t = [app.data_right_pos_t app.listen_ack_counter];
%                 app.data_right_pos_val = [app.data_right_pos_val app.send_traj.tgt_state(3,app.listen_ack_counter)];
%                 
%                 app.data_left_vel_t = [app.data_left_vel_t app.listen_ack_counter];
%                 app.data_left_vel_val = [app.data_left_vel_val app.send_traj.tgt_state(5,app.listen_ack_counter)];
%                 
%                 app.data_right_vel_t = [app.data_right_vel_t app.listen_ack_counter];
%                 app.data_right_vel_val = [app.data_right_vel_val app.send_traj.tgt_state(6,app.listen_ack_counter)];
%                 
%                 pause(0.001);
%                 x = strcat('receive', num2str(app.listen_ack_counter), 'ack package');
%                 disp(x)
%             end
%             plot(app.AxesLeftPos, app.data_left_pos_t, app.data_left_pos_val);
%             plot(app.AxesRightPos, app.data_right_pos_t, app.data_right_pos_val);
%             plot(app.AxesLeftVel, app.data_left_vel_t, app.data_left_vel_val);
%             plot(app.AxesRightVel, app.data_right_vel_t, app.data_right_vel_val);
%             app.data_left_pos_t = [];
%             app.data_left_pos_val = [];
%             app.data_right_pos_t = [];
%             app.data_right_pos_val = [];
%             app.data_left_vel_t = [];
%             app.data_left_vel_val = [];
%             app.data_right_vel_t = [];
%             app.data_right_vel_val = [];
%             app.listen_ack_counter = 0;
        end

        % Button pushed function: clearplotButton
        function clearplotButtonPushed(app, event)
            cla(app.AxesLeftPos);
            cla(app.AxesLeftVel);
            cla(app.AxesRightPos);
            cla(app.AxesRightVel);
            
        end

        % Button pushed function: SetdelayButton
        function SetdelayButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(20);    
            SendData.value = int16(app.delaytimemsEditField.Value); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            x = strcat('set delay to be  ', num2str(app.delaytimemsEditField.Value), ' ms');
            disp(x)
            pause(0.2);
            % take ACK
            A = fread(app.s,1,'uchar');
            if (A(1) == uint8(170)) % the head of a frame 
                B = fread(app.s,13,'uchar');
                crc = B(12:13);
                crc16 = crcccitt([A(1);B(1:11)]);
                my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian

                if (sum(my_crc == crc) == 2)
                    ReceiveData.type = B(1);  
                    if (ReceiveData.type == 40)  %  ACK 
                        disp('set delay ack')
                    end
                end
            end            
        end

        % Button pushed function: SetPosKpButton
        function SetPosKpButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(25);    
            SendData.value = int16(0); 
            SendData.position = int32(app.LeftKpEditField.Value);
            SendData.velocity = int32(app.RightKpEditField.Value);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            x = strcat('set left Kp to be  ', num2str(app.LeftKpEditField.Value));
            disp(x)
            x = strcat('set right Kp to be  ', num2str(app.RightKpEditField.Value));
            disp(x)
            pause(0.2);
            % take ACK
            A = fread(app.s,1,'uchar');
            if (A(1) == uint8(170)) % the head of a frame 
                B = fread(app.s,13,'uchar');
                crc = B(12:13);
                crc16 = crcccitt([A(1);B(1:11)]);
                my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian

                if (sum(my_crc == crc) == 2)
                    ReceiveData.type = B(1);  
                    if (ReceiveData.type == 40)  %  ACK 
                        disp('set PID pos Kp ack')
                    end
                end
            end               
        end

        % Button pushed function: SetdebugprintButton
        function SetdebugprintButtonPushed(app, event)
            app.debugoptionListBox.Value
            SendData.flag = uint8(170);
            SendData.type = uint8(1);    
            SendData.value = int16(0); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            x = strcat('set left Kp to be  ', num2str(app.LeftKpEditField.Value));
            disp(x)
            x = strcat('set right Kp to be  ', num2str(app.RightKpEditField.Value));
            disp(x)
            pause(0.2);
            % take ACK
            A = fread(app.s,1,'uchar');
            if (A(1) == uint8(170)) % the head of a frame 
                B = fread(app.s,13,'uchar');
                crc = B(12:13);
                crc16 = crcccitt([A(1);B(1:11)]);
                my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian

                if (sum(my_crc == crc) == 2)
                    ReceiveData.type = B(1);  
                    if (ReceiveData.type == 40)  %  ACK 
                        disp('set PID pos Kp ack')
                    end
                end
            end        
        end

        % Button pushed function: SetcloseButton
        function SetcloseButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(28);    
            SendData.value = int16(app.closetimepercentEditField.Value*1000); 
            SendData.position = int32(0);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            x = strcat('set close time to be  ', num2str(app.closetimepercentEditField.Value), ' percent');
            disp(x)
            pause(0.2);
            % take ACK
            A = fread(app.s,1,'uchar');
            if (A(1) == uint8(170)) % the head of a frame 
                B = fread(app.s,13,'uchar');
                crc = B(12:13);
                crc16 = crcccitt([A(1);B(1:11)]);
                my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian

                if (sum(my_crc == crc) == 2)
                    ReceiveData.type = B(1);  
                    if (ReceiveData.type == 40)  %  ACK 
                        disp('set close ack')
                    end
                end
            end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: SetInitKpButton
        function SetInitKpButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(26);    
            SendData.value = int16(0); 
            SendData.position = int32(app.InitKpEditField.Value);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            x = strcat('set pos Kp to be  ', num2str(app.InitKpEditField.Value));
            disp(x)
            pause(0.2);
            % take ACK
            A = fread(app.s,1,'uchar');
            if (A(1) == uint8(170)) % the head of a frame 
                B = fread(app.s,13,'uchar');
                crc = B(12:13);
                crc16 = crcccitt([A(1);B(1:11)]);
                my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian

                if (sum(my_crc == crc) == 2)
                    ReceiveData.type = B(1);  
                    if (ReceiveData.type == 40)  %  ACK 
                        disp('set Init pos Kp ack')
                    end
                end
            end 
        end

        % Button pushed function: SetInitKiButton
        function SetInitKiButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(26);    
            SendData.value = int16(0); 
            SendData.position = int32(app.InitKiEditField.Value);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            x = strcat('set pos Ki to be  ', num2str(app.InitKiEditField.Value));
            disp(x)
            pause(0.2);
            % take ACK
            A = fread(app.s,1,'uchar');
            if (A(1) == uint8(170)) % the head of a frame 
                B = fread(app.s,13,'uchar');
                crc = B(12:13);
                crc16 = crcccitt([A(1);B(1:11)]);
                my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian

                if (sum(my_crc == crc) == 2)
                    ReceiveData.type = B(1);  
                    if (ReceiveData.type == 40)  %  ACK 
                        disp('set Init pos Ki ack')
                    end
                end
            end 
        end

        % Button pushed function: SetInitKdButton
        function SetInitKdButtonPushed(app, event)
            SendData.flag = uint8(170);
            SendData.type = uint8(26);    
            SendData.value = int16(0); 
            SendData.position = int32(app.InitKdEditField.Value);
            SendData.velocity = int32(0);
            SendData.crc = pack_and_cal_crc(SendData);
            send_struct(app.s, SendData);
            x = strcat('set pos Kd to be  ', num2str(app.InitKdEditField.Value));
            disp(x)
            pause(0.2);
            % take ACK
            A = fread(app.s,1,'uchar');
            if (A(1) == uint8(170)) % the head of a frame 
                B = fread(app.s,13,'uchar');
                crc = B(12:13);
                crc16 = crcccitt([A(1);B(1:11)]);
                my_crc = [uint8(bi2de(bitget(crc16, 1:8)));uint8(bitshift(crc16, -8))];        % pay attention to the order because of little endian

                if (sum(my_crc == crc) == 2)
                    ReceiveData.type = B(1);  
                    if (ReceiveData.type == 40)  %  ACK 
                        disp('set Init pos Kd ack')
                    end
                end
            end 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.OpenSerialPortButton = uibutton(app.UIFigure, 'push');
            app.OpenSerialPortButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSerialPortButtonPushed, true);
            app.OpenSerialPortButton.Text = 'Open Serial Port';
            app.CloseSerialPortButton = uibutton(app.UIFigure, 'push');
            app.CloseSerialPortButton.ButtonPushedFcn = createCallbackFcn(app, @CloseSerialPortButtonPushed, true);
            app.CloseSerialPortButton.Text = 'Close Serial Port';
            app.readtrajectoryButton = uibutton(app.UIFigure, 'push');
            app.readtrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @readtrajectoryButtonPushed, true);
            app.readtrajectoryButton.Text = 'read trajectory';
            app.downloadtrajectorytorobotButton = uibutton(app.UIFigure, 'push');
            app.downloadtrajectorytorobotButton.ButtonPushedFcn = createCallbackFcn(app, @downloadtrajectorytorobotButtonPushed, true);
            app.downloadtrajectorytorobotButton.Enable = 'off';
            app.downloadtrajectorytorobotButton.Text = 'download trajectory to robot';
            app.OpenLEFTgrapperButton = uibutton(app.UIFigure, 'push');
            app.OpenLEFTgrapperButton.ButtonPushedFcn = createCallbackFcn(app, @OpenLEFTgrapperButtonPushed, true);
            app.OpenLEFTgrapperButton.Enable = 'off';
            app.OpenLEFTgrapperButton.Text = 'Open LEFT grapper';
            app.CloseLEFTgrapperButton = uibutton(app.UIFigure, 'push');
            app.CloseLEFTgrapperButton.ButtonPushedFcn = createCallbackFcn(app, @CloseLEFTgrapperButtonPushed, true);
            app.CloseLEFTgrapperButton.Enable = 'off';
            app.CloseLEFTgrapperButton.Text = 'Close LEFT grapper';
            app.OpenRIGHTgrapperButton = uibutton(app.UIFigure, 'push');
            app.OpenRIGHTgrapperButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRIGHTgrapperButtonPushed, true);
            app.OpenRIGHTgrapperButton.Enable = 'off';
            app.OpenRIGHTgrapperButton.Text = 'Open RIGHT grapper';
            app.CloseRIGHTgrapperButton = uibutton(app.UIFigure, 'push');
            app.CloseRIGHTgrapperButton.ButtonPushedFcn = createCallbackFcn(app, @CloseRIGHTgrapperButtonPushed, true);
            app.CloseRIGHTgrapperButton.Enable = 'off';
            app.CloseRIGHTgrapperButton.Text = 'Close RIGHT grapper';
            app.SwingRIGHTInitPoseButton = uibutton(app.UIFigure, 'push');
            app.SwingRIGHTInitPoseButton.ButtonPushedFcn = createCallbackFcn(app, @SwingRIGHTInitPoseButtonPushed, true);
            app.SwingRIGHTInitPoseButton.Enable = 'off';
            app.SwingRIGHTInitPoseButton.Text = 'Swing RIGHT Init Pose';
            app.SwingRIGHTExecutetrajectoryButton = uibutton(app.UIFigure, 'push');
            app.SwingRIGHTExecutetrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SwingRIGHTExecutetrajectoryButtonPushed, true);
            app.SwingRIGHTExecutetrajectoryButton.Enable = 'off';
            app.SwingRIGHTExecutetrajectoryButton.Text = {'Swing RIGHT '; 'Execute trajectory'};
            app.AbortAllActionsButton = uibutton(app.UIFigure, 'push');
            app.AbortAllActionsButton.ButtonPushedFcn = createCallbackFcn(app, @AbortAllActionsButtonPushed, true);
            app.AbortAllActionsButton.Enable = 'off';
            app.AbortAllActionsButton.Text = 'Abort All Actions';
            app.SendNotifyMsgButton = uibutton(app.UIFigure, 'push');
            app.SendNotifyMsgButton.ButtonPushedFcn = createCallbackFcn(app, @SendNotifyMsgButtonPushed, true);
            app.SendNotifyMsgButton.Enable = 'off';
            app.SendNotifyMsgButton.Text = 'Send Notify Msg';
            app.trajectoryfilesListBoxLabel = uilabel(app.UIFigure);
            app.trajectoryfilesListBoxLabel.Text = 'trajectory files';
            app.trajectoryfilesListBox = uilistbox(app.UIFigure);
            app.SwingLEFTInitPoseButton = uibutton(app.UIFigure, 'push');
            app.SwingLEFTInitPoseButton.ButtonPushedFcn = createCallbackFcn(app, @SwingLEFTInitPoseButtonPushed, true);
            app.SwingLEFTInitPoseButton.Enable = 'off';
            app.SwingLEFTInitPoseButton.Text = 'Swing LEFT Init Pose';
            app.SwingLEFTExecutetrajectoryButton = uibutton(app.UIFigure, 'push');
            app.SwingLEFTExecutetrajectoryButton.Enable = 'off';
            app.SwingLEFTExecutetrajectoryButton.Text = {'Swing LEFT'; 'Execute trajectory'};
            app.SwingdirectionListBoxLabel = uilabel(app.UIFigure);
            app.SwingdirectionListBoxLabel.Text = 'Swing direction';
            app.SwingdirectionListBox = uilistbox(app.UIFigure);
            app.SwingdirectionListBox.Items = {'Forward', 'Backward'};
            app.SwingdirectionListBox.Value = 'Forward';
            app.AxesLeftPos = uiaxes(app.UIFigure);
            title(app.AxesLeftPos, 'Left motor pos')
            xlabel(app.AxesLeftPos, 'X')
            ylabel(app.AxesLeftPos, 'Y')
            app.AxesRightPos = uiaxes(app.UIFigure);
            title(app.AxesRightPos, {'Right motor pos'; ''})
            xlabel(app.AxesRightPos, 'X')
            ylabel(app.AxesRightPos, 'Y')
            app.OpenBOTHButton = uibutton(app.UIFigure, 'push');
            app.OpenBOTHButton.ButtonPushedFcn = createCallbackFcn(app, @OpenBOTHButtonPushed, true);
            app.OpenBOTHButton.Enable = 'off';
            app.OpenBOTHButton.Text = 'Open BOTH';
            app.CloseBOTHButton = uibutton(app.UIFigure, 'push');
            app.CloseBOTHButton.ButtonPushedFcn = createCallbackFcn(app, @CloseBOTHButtonPushed, true);
            app.CloseBOTHButton.Enable = 'off';
            app.CloseBOTHButton.Text = 'Close BOTH';
            app.AxesLeftVel = uiaxes(app.UIFigure);
            title(app.AxesLeftVel, 'Left motor vel')
            xlabel(app.AxesLeftVel, 'X')
            ylabel(app.AxesLeftVel, 'Y')
            app.AxesRightVel = uiaxes(app.UIFigure);
            title(app.AxesRightVel, 'Right motor vel')
            xlabel(app.AxesRightVel, 'X')
            ylabel(app.AxesRightVel, 'Y')
            app.PlottrajectoryButton = uibutton(app.UIFigure, 'push');
            app.PlottrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlottrajectoryButtonPushed, true);
            app.PlottrajectoryButton.Enable = 'off';
            app.PlottrajectoryButton.Text = 'Plot trajectory';
            app.GetfeedbacktrajectoryButton = uibutton(app.UIFigure, 'push');
            app.GetfeedbacktrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GetfeedbacktrajectoryButtonPushed, true);
            app.GetfeedbacktrajectoryButton.Enable = 'off';
            app.GetfeedbacktrajectoryButton.Text = 'Get feedback trajectory';
            app.clearplotButton = uibutton(app.UIFigure, 'push');
            app.clearplotButton.ButtonPushedFcn = createCallbackFcn(app, @clearplotButtonPushed, true);
            app.clearplotButton.Text = 'clear plot';
            app.NoteforscreenshootEditFieldLabel = uilabel(app.UIFigure);
            app.NoteforscreenshootEditFieldLabel.Text = 'Note for screenshoot:';
            app.NoteforscreenshootEditField = uieditfield(app.UIFigure, 'text');
            app.delaytimemsEditFieldLabel = uilabel(app.UIFigure);
            app.delaytimemsEditFieldLabel.Text = 'delay time (ms)';
            app.delaytimemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.delaytimemsEditField.Value = 95;
            app.SetdelayButton = uibutton(app.UIFigure, 'push');
            app.SetdelayButton.ButtonPushedFcn = createCallbackFcn(app, @SetdelayButtonPushed, true);
            app.SetdelayButton.Text = 'Set delay';
            app.LeftKpEditFieldLabel = uilabel(app.UIFigure);
            app.LeftKpEditFieldLabel.Text = 'Left Kp';
            app.LeftKpEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftKpEditField.Value = 380;
            app.RightKpEditFieldLabel = uilabel(app.UIFigure);
            app.RightKpEditFieldLabel.Text = 'Right Kp';
            app.RightKpEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightKpEditField.Value = 380;
            app.SetPosKpButton = uibutton(app.UIFigure, 'push');
            app.SetPosKpButton.ButtonPushedFcn = createCallbackFcn(app, @SetPosKpButtonPushed, true);
            app.SetPosKpButton.Text = 'Set Pos Kp';
            app.debugoptionListBoxLabel = uilabel(app.UIFigure);
            app.debugoptionListBoxLabel.Text = 'debug option';
            app.debugoptionListBox = uilistbox(app.UIFigure);
            app.debugoptionListBox.Items = {'0', '1', '2'};
            app.debugoptionListBox.Value = '0';
            app.SetdebugprintButton = uibutton(app.UIFigure, 'push');
            app.SetdebugprintButton.ButtonPushedFcn = createCallbackFcn(app, @SetdebugprintButtonPushed, true);
            app.SetdebugprintButton.Text = 'Set debugprint';
            app.SetPosKiButton = uibutton(app.UIFigure, 'push');
            app.SetPosKiButton.Text = 'Set Pos Ki';
            app.LeftKiEditFieldLabel = uilabel(app.UIFigure);
            app.LeftKiEditFieldLabel.Text = 'Left Ki';
            app.LeftKiEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftKiEditField.Value = 0.0001;
            app.RightKiEditFieldLabel = uilabel(app.UIFigure);
            app.RightKiEditFieldLabel.Text = 'Right Ki';
            app.RightKiEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightKiEditField.Value = 0.0001;
            app.SetPosKdButton = uibutton(app.UIFigure, 'push');
            app.SetPosKdButton.Text = 'Set Pos Kd';
            app.LeftKdEditFieldLabel = uilabel(app.UIFigure);
            app.LeftKdEditFieldLabel.Text = 'Left Kd';
            app.LeftKdEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeftKdEditField.Value = 0.01;
            app.RightKdEditFieldLabel = uilabel(app.UIFigure);
            app.RightKdEditFieldLabel.Text = 'Right Kd';
            app.RightKdEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightKdEditField.Value = 0.01;
            app.SetVelKpButton = uibutton(app.UIFigure, 'push');
            app.SetVelKpButton.Text = 'Set Vel Kp';
            app.SetVelKiButton = uibutton(app.UIFigure, 'push');
            app.SetVelKiButton.Text = 'Set Vel Ki';
            app.SetVelKdButton = uibutton(app.UIFigure, 'push');
            app.SetVelKdButton.Text = 'Set Vel Kd';
            app.LeftKpEditField_4Label = uilabel(app.UIFigure);
            app.LeftKpEditField_4Label.Text = 'Left Kp';
            app.LeftKpEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.LeftKpEditField_4.Value = 8;
            app.RightKpEditField_4Label = uilabel(app.UIFigure);
            app.RightKpEditField_4Label.Text = 'Right Kp';
            app.RightKpEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.RightKpEditField_4.Value = 8;
            app.LeftKiEditField_2Label = uilabel(app.UIFigure);
            app.LeftKiEditField_2Label.Text = 'Left Ki';
            app.LeftKiEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LeftKiEditField_2.Value = 1e-05;
            app.RightKiEditField_2Label = uilabel(app.UIFigure);
            app.RightKiEditField_2Label.Text = 'Right Ki';
            app.RightKiEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RightKiEditField_2.Value = 1e-05;
            app.LeftKdEditField_2Label = uilabel(app.UIFigure);
            app.LeftKdEditField_2Label.Text = 'Left Kd';
            app.LeftKdEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LeftKdEditField_2.Value = 0.06;
            app.RightKdEditField_2Label = uilabel(app.UIFigure);
            app.RightKdEditField_2Label.Text = 'Right Kd';
            app.RightKdEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RightKdEditField_2.Value = 0.06;
            app.SetcloseButton = uibutton(app.UIFigure, 'push');
            app.SetcloseButton.ButtonPushedFcn = createCallbackFcn(app, @SetcloseButtonPushed, true);
            app.SetcloseButton.Text = 'Set close';
            app.closetimepercentEditFieldLabel = uilabel(app.UIFigure);
            app.closetimepercentEditFieldLabel.Text = 'close time(percent)';
            app.closetimepercentEditField = uieditfield(app.UIFigure, 'numeric');
            app.closetimepercentEditField.Value = 0.9;
            app.SetInitKpButton = uibutton(app.UIFigure, 'push');
            app.SetInitKpButton.ButtonPushedFcn = createCallbackFcn(app, @SetInitKpButtonPushed, true);
            app.SetInitKpButton.Text = 'Set Init Kp';
            app.SetInitKiButton = uibutton(app.UIFigure, 'push');
            app.SetInitKiButton.ButtonPushedFcn = createCallbackFcn(app, @SetInitKiButtonPushed, true);
            app.SetInitKiButton.Text = 'Set Init Ki';
            app.SetInitKdButton = uibutton(app.UIFigure, 'push');
            app.SetInitKdButton.ButtonPushedFcn = createCallbackFcn(app, @SetInitKdButtonPushed, true);
            app.SetInitKdButton.Text = 'Set Init Kd';
            app.InitKpEditFieldLabel = uilabel(app.UIFigure);
            app.InitKpEditFieldLabel.Text = 'Init Kp';
            app.InitKpEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitKpEditField.Value = 8;
            app.InitKiEditFieldLabel = uilabel(app.UIFigure);
            app.InitKiEditFieldLabel.Text = 'Init Ki';
            app.InitKiEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitKiEditField.Value = 1e-06;
            app.InitKdEditFieldLabel = uilabel(app.UIFigure);
            app.InitKdEditFieldLabel.Text = 'Init Kd';
            app.InitKdEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitKdEditField.Value = 0.06;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Control_APP

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end